classdef APP_Filtres_BOUSSOU_Walid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FichierMenu                    matlab.ui.container.Menu
        ChoisirlimageMenu              matlab.ui.container.Menu
        EnregistrerlimageMenu          matlab.ui.container.Menu
        EnregistrerpuisquitterMenu     matlab.ui.container.Menu
        QuitterMenu                    matlab.ui.container.Menu
        EditionMenu                    matlab.ui.container.Menu
        AnnulerMenu                    matlab.ui.container.Menu
        ReappliquerMenu                matlab.ui.container.Menu
        RessayerMenu                   matlab.ui.container.Menu
        TransofmrationMenu             matlab.ui.container.Menu
        MiroirMenu                     matlab.ui.container.Menu
        Rotation180Menu                matlab.ui.container.Menu
        FiltresMenu                    matlab.ui.container.Menu
        GrisMenu                       matlab.ui.container.Menu
        BruitMenu                      matlab.ui.container.Menu
        BruitMenu_2                    matlab.ui.container.Menu
        ImpulseMenu                    matlab.ui.container.Menu
        GaussianMenu                   matlab.ui.container.Menu
        MedianMenu                     matlab.ui.container.Menu
        MoyenMenu                      matlab.ui.container.Menu
        ContactMenu                    matlab.ui.container.Menu
        LinkedInMenu                   matlab.ui.container.Menu
        FacebookMenu                   matlab.ui.container.Menu
        EmailMenu                      matlab.ui.container.Menu
        CVMenu                         matlab.ui.container.Menu
        HTML_7                         matlab.ui.control.HTML
        Switch                         matlab.ui.control.Switch
        HTML_6                         matlab.ui.control.HTML
        RessayerButton                 matlab.ui.control.Button
        autorise                       matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        HTML_5                         matlab.ui.control.HTML
        HTML_2                         matlab.ui.control.HTML
        HTML                           matlab.ui.control.HTML
        Image_2                        matlab.ui.control.Image
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        EnregistrerlimageButton        matlab.ui.control.Button
        RalisparBOUSSOUWalidLabel      matlab.ui.control.Label
        ChoisirlimageButton            matlab.ui.control.Button
        PROJETTRAITEMENTDIMAGESLabel   matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Label_5                        matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        RotationTab                    matlab.ui.container.Tab
        ValeursKnob                    matlab.ui.control.Knob
        ValeursKnobLabel               matlab.ui.control.Label
        AppliquerButton                matlab.ui.control.Button
        RotationLabel                  matlab.ui.control.Label
        LuminanceTab                   matlab.ui.container.Tab
        LuminanceLabel                 matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        LuminanceMoinsButton           matlab.ui.control.Button
        LuminancePlusButton            matlab.ui.control.Button
        GrisTab                        matlab.ui.container.Tab
        GreyScaleButton                matlab.ui.control.Button
        FiltreGrisLabel                matlab.ui.control.Label
        BruitTab                       matlab.ui.container.Tab
        BruitImpulseButton             matlab.ui.control.Button
        BruitButton                    matlab.ui.control.Button
        BruitLabel                     matlab.ui.control.Label
        FiltresflouTab                 matlab.ui.container.Tab
        FiltresButtonGroup             matlab.ui.container.ButtonGroup
        Spinner_3                      matlab.ui.control.Spinner
        MoyenButton                    matlab.ui.control.Button
        GaussianButton                 matlab.ui.control.Button
        MedianButton                   matlab.ui.control.Button
        ContourTab                     matlab.ui.container.Tab
        ContoursLabel                  matlab.ui.control.Label
        RobertButton                   matlab.ui.control.Button
        SobelButton                    matlab.ui.control.Button
        PrewittButton                  matlab.ui.control.Button
        MiroirTab                      matlab.ui.container.Tab
        MiroirVerticalButton           matlab.ui.control.Button
        MiroirHorizontalButton         matlab.ui.control.Button
        MiroirLabel                    matlab.ui.control.Label
        HistogrammeTab                 matlab.ui.container.Tab
        FiltreLabel                    matlab.ui.control.Label
        HistogrammeEqualizationButton  matlab.ui.control.Button
        PlotsLabel                     matlab.ui.control.Label
        HistogrammeButton              matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        SeuillageTab                   matlab.ui.container.Tab
        Spinner_2                      matlab.ui.control.Spinner
        SeuillageButton                matlab.ui.control.Button
        SeuillageLabel                 matlab.ui.control.Label
        LUTTab                         matlab.ui.container.Tab
        MINLabel                       matlab.ui.control.Label
        MAXLabel                       matlab.ui.control.Label
        Slider_MIN                     matlab.ui.control.Slider
        AppliquerButton_3              matlab.ui.control.Button
        LUTLabel                       matlab.ui.control.Label
        Slider_MAX                     matlab.ui.control.Slider
        ConvolutionTab                 matlab.ui.container.Tab
        PSExempledeembossFilterK220270000Label  matlab.ui.control.Label
        EditField_9                    matlab.ui.control.NumericEditField
        EditField_8                    matlab.ui.control.NumericEditField
        EditField_7                    matlab.ui.control.NumericEditField
        EditField_6                    matlab.ui.control.NumericEditField
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        AppliquerButton_2              matlab.ui.control.Button
        ConvolutionLabel               matlab.ui.control.Label
        CropTab                        matlab.ui.container.Tab
        WLabel                         matlab.ui.control.Label
        HLabel_2                       matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        AppliquerButton_4              matlab.ui.control.Button
        XLabel                         matlab.ui.control.Label
        courdheight                    matlab.ui.control.NumericEditField
        courdwidth                     matlab.ui.control.NumericEditField
        courdY                         matlab.ui.control.NumericEditField
        courdX                         matlab.ui.control.NumericEditField
        CROPLabel                      matlab.ui.control.Label
        RsolutionTab                   matlab.ui.container.Tab
        resize                         matlab.ui.control.Spinner
        AppliquerButton_5              matlab.ui.control.Button
        EchantillonageLabel            matlab.ui.control.Label
        ImagesTab                      matlab.ui.container.Tab
        SoustractionButton             matlab.ui.control.Button
        MultiplicationButton           matlab.ui.control.Button
        AdditionButton                 matlab.ui.control.Button
        FourierButton                  matlab.ui.control.Button
        Operations2imagesLabel         matlab.ui.control.Label
        AutresTab                      matlab.ui.container.Tab
        Hyperlink                      matlab.ui.control.Hyperlink
        FiltresLabel                   matlab.ui.control.Label
        ErosionButton                  matlab.ui.control.Button
        DilatationButton               matlab.ui.control.Button
        SaturationButton               matlab.ui.control.Button
        NegatifButton                  matlab.ui.control.Button
        BaseTVButton                   matlab.ui.control.Button
        OperationsLabel                matlab.ui.control.Label
        autorise_2                     matlab.ui.control.Label
        Lamp_2                         matlab.ui.control.Lamp
        ChoisirlimageButton_2          matlab.ui.control.Button
        Label_6                        matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
    end


    properties (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ChoisirlimageMenu
        function ChoisirlimageMenuSelected(app, event)
            global a;
            [filename, pathname] = uigetfile('*.*', 'Ouvrir Image');
            filename=strcat(pathname,filename);
            if filename~= 0
                a=imread(filename);


                %             imshow(a,'Parent',app.UIAxes);
                %             axis(app.UIAxes, 'tight');
                app.UIFigure.Name = 'BOUSSOU Walid App';
                app.autorise_2.Visible='off';
                app.Lamp_2.Visible='off';
                app.ChoisirlimageButton_2.Visible='off';
                app.Label_6.Visible='off';
                app.HTML_7.Visible='off';

                app.autorise.Visible='on';
                app.Lamp.Visible='on';
                app.ChoisirlimageButton.Visible='on';
                app.Label_5.Visible='on';
                app.HTML.Visible='on';
                app.EnregistrerlimageMenu.Visible='on';
                app.EnregistrerpuisquitterMenu.Visible='on';
                app.AnnulerMenu.Visible='on';
                app.ReappliquerMenu.Visible='on';
                app.RessayerMenu.Visible='on';
                app.TransofmrationMenu.Visible='on';
                app.GrisMenu.Visible='on';
                app.BruitMenu.Visible='on';
                app.GaussianMenu.Visible='on';
                app.MedianMenu.Visible='on';
                app.MoyenMenu.Visible='on';


                axis(app.UIAxes2, 'tight');
                app.Lamp.Color='green';
                app.autorise.Text='Appliquez des filtres';
                app.TabGroup.Visible = 'on';
                app.HTML_6.Visible = 'on';
                app.RessayerButton.Visible = 'on';
                app.HTML_2.Visible = 'on';
                app.EnregistrerlimageButton.Visible = 'on';
                app.UIAxes.Visible = 'on';
                app.UIAxes2.Visible = 'on';
                app.Switch.Visible = 'on';
                imshow(a,'Parent',app.UIAxes2);
            end
        end

        % Button pushed function: ChoisirlimageButton_2
        function ChoisirlimageButton_2Pushed(app, event)
            ChoisirlimageMenuSelected(app, event);
            %             global a;
            %             [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            %             filename=strcat(pathname,filename);
            %             a=imread(filename);
            %
            %             %             imshow(a,'Parent',app.UIAxes);
            %             %             axis(app.UIAxes, 'tight');
            %             app.autorise_2.Visible='off';
            %             app.Lamp_2.Visible='off';
            %             app.ChoisirlimageButton_2.Visible='off';
            %             app.Label_6.Visible='off';
            %             app.HTML_7.Visible='off';
            %
            %             app.autorise.Visible='on';
            %             app.Lamp.Visible='on';
            %             app.ChoisirlimageButton.Visible='on';
            %             app.Label_5.Visible='on';
            %             app.HTML.Visible='on';
            %             app.EnregistrerlimageMenu.Visible='on';
            %             app.EnregistrerpuisquitterMenu.Visible='on';
            %             app.AnnulerMenu.Visible='on';
            %             app.ReappliquerMenu.Visible='on';
            %             app.RessayerMenu.Visible='on';
            %
            %
            %
            %             axis(app.UIAxes2, 'tight');
            %             app.Lamp.Color='green';
            %             app.autorise.Text='Appliquez des filtres';
            %             app.TabGroup.Visible = 'on';
            %             app.HTML_6.Visible = 'on';
            %             app.RessayerButton.Visible = 'on';
            %             app.HTML_2.Visible = 'on';
            %             app.EnregistrerlimageButton.Visible = 'on';
            %             app.UIAxes.Visible = 'on';
            %             app.UIAxes2.Visible = 'on';
            %             app.Switch.Visible = 'on';
            %             imshow(a,'Parent',app.UIAxes2);


        end

        % Button pushed function: ChoisirlimageButton
        function ChoisirlimageButtonPushed(app, event)
            global a;
            %             global q;
            [filename, pathname] = uigetfile('*.*', 'Ouvrir Image');
            filename=strcat(pathname,filename);
            if filename~= 0
                a=imread(filename);
                %a=imread('tetouan.jpg');
                %             import java.util.Stack
                %             q = Stack();
                %             q.push(a);
                %             b=q.pop()

                %             imshow(app.Original,'Parent',app.UIAxes);
                %             axis(app.UIAxes, 'tight');
                imshow(a,'Parent',app.UIAxes2);
                axis(app.UIAxes2, 'tight');
                app.Lamp.Color='green';
                app.autorise.Text='Appliquez des filtres';
                %             app.TabGroup.Visible = 'on';
                %             app.HTML_6.Visible = 'on';
                %             app.RessayerButton.Visible = 'on';
                %             app.HTML_2.Visible = 'on';
                %             app.EnregistrerlimageButton.Visible = 'on';
                %             app.UIAxes.Visible = 'on';
                %             app.UIAxes2.Visible = 'on';
                %             app.Switch.Visible = 'on';
                %             app.Label_5.Position = [430 2 198 556];
            end
        end

        % Button pushed function: EnregistrerlimageButton
        function EnregistrerlimageButtonPushed(app, event)
            im1=getimage(app.UIAxes2);
            [filename, pathname] = uiputfile('image_filtre.jpg', 'Enregitrer Image');
            filename=strcat(pathname,filename);
            %             if(filename== " ")
            %                 return;
            %             end
            if filename ~=0
                imwrite(im1,filename,'jpg');
                figure;
                imshow(filename);
            end
            % im=findobj(app.UIAxes2, 'type', 'image'); %ca marche mais type
            % Image et il veut pas convertir
            %im=findall(app.UIAxes2, 'type', 'image');

            %im=imhandles(app.UIAxes2); undefined
            %              im1 = double(im);
            %              I = ind2rgb(im1,Mcolor);
            % im1 = uint8(normalize(im, 'range', [0 255]));
            %imwrite(im, 'F:/output/filename.jpg', 'jpg'));


            %             axis(app.UIAxes2, 'tight');
            %             f=getframe(app.UIAxes2);
            %             im1 = f.cdata;


        end

        % Button pushed function: RessayerButton
        function RessayerButtonPushed(app, event)
            global a;
            %             app.Lamp.Color='red';
            %             app.autorise.Text=' ';
            imshow(a,'Parent',app.UIAxes2);
            %             app.Lamp.Color='green';
            %             app.autorise.Text='Appliquez des filtres';
        end

        % Button pushed function: GreyScaleButton
        function GreyScaleButtonPushed(app, event)

            global J;
            global K;
            %app.Filter=app.Original;
            J=getimage(app.UIAxes2);
            K=J;
            J =im2gray(J);
            %q.add(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: LuminanceMoinsButton
        function LuminanceMoinsButtonPushed(app, event)

            %             f=getframe(app.UIAxes2);
            %             im1 = f.cdata;

            global J;
            global K;
            J=getimage(app.UIAxes2);
            K=J;
            R=app.Slider.Value;

            J =luminanceMoins(J,R);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: LuminancePlusButton
        function LuminancePlusButtonPushed(app, event)
            %             f=getframe(app.UIAxes2);
            %             im1 = f.cdata;

            global J;

            J=getimage(app.UIAxes2);
            global K;
            K=J;
            R=app.Slider.Value;

            J =luminancePlus(J,R);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: MedianButton
        function MedianButtonPushed(app, event)

            global J;
            J=getimage(app.UIAxes2);
            global K;
            K=J;
            %             red_channel = J(:, :, 1);
            %             green_channel = J(:, :, 2);
            %             blue_channel = J(:, :, 3);

            J = im2gray(J);
            R=app.Spinner_3.Value;
            J=medfilt2(J, [R R]);
            %             red_channel = medfilt2(red_channel, [10 10]);
            %             green_channel = medfilt2(green_channel, [10 10]);
            %             blue_channel = medfilt2(blue_channel, [10 10]);
            %
            %             J = cat(3, red_channel, green_channel, blue_channel);
            %J =medfilt2(J, [2 2]); %K =FiltreMedian(K ,3);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)

            global J;
            J=getimage(app.UIAxes2);
            global K;
            K=J;
            %J =Gaussian(J);
            %K =FiltreGaussien(K ,5, 7);
            %             J =imgaussfilt3(J);
            % prompt = {'filter size'};
            %             dlgtitle = 'Gaussian Blur';
            %             dims = [1 35];
            %             definput = {''};
            %             answer = inputdlg(prompt,dlgtitle,dims,definput);
            %             size = str2num(answer{1});
            R=app.Spinner_3.Value;
            J= imgaussfilt(J,R);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
            %             global a;
            %             img1=rgb2gray(a);
            %             img1=Gaussian_Tas(img1);
            %             imshow(img1,'Parent',app.UIAxes2);
            %             axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: MoyenButton
        function MoyenButtonPushed(app, event)

            global J;
            J=getimage(app.UIAxes2);
            global K;
            K=J;
            J = im2gray(J);
            R=app.Spinner_3.Value;
            matrix = ones(R,R)/(R*R);
            J = imfilter(J, matrix);
            %             J=im2gray(getimage(app.UIAxes2));
            %             J =Medium(J);%K =FiltreMoyenne(K ,5);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: MiroirVerticalButton
        function MiroirVerticalButtonPushed(app, event)
            %ROTATION


            global J;

            J=getimage(app.UIAxes2);
            global K;
            K=J;

            J =imrotate(J,180);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');

            %             global J;
            %
            %             J=getimage(app.UIAxes2);
            %             global K;
            %             K=J;
            %             J =miroireV(J);
            %             imshow(J,'Parent',app.UIAxes2);
            %             axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: MiroirHorizontalButton
        function MiroirHorizontalButtonPushed(app, event)

            global J;

            J=getimage(app.UIAxes2);
            global K;
            K=J;
            J =miroireH(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');

        end

        % Button pushed function: HistogrammeButton
        function HistogrammeButtonPushed(app, event)

            global H;
            H=im2gray(getimage(app.UIAxes2));
            H =histograme(H);
            %             cla(app.UIAxes2, 'reset');
            %
            %             app.UIAxes2.XColor='0.07,0.62,1.00';
            %             app.UIAxes2.YColor='0.00,0.45,0.74';
            %             app.UIAxes2.GridColor='0.00,0.45,0.74';
            %             app.UIAxes2.Title.String='Resultat';
            %             app.UIAxes2.FontName='Algerian';
            bar('Parent',app.UIAxes,H);

        end

        % Button pushed function: HistogrammeEqualizationButton
        function HistogrammeEqualizationButtonPushed(app, event)

            global J;
            J=getimage(app.UIAxes2);
            global K;
            K=J;

            J=im2gray(getimage(app.UIAxes2)); %a;
            J =histogramme_equalization(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: AppliquerButton
        function AppliquerButtonPushed(app, event)

            %ROTATION


            global J;

            J=getimage(app.UIAxes2);
            global K;
            K=J;
            R=app.ValeursKnob.Value;
            J =imrotate(J,R);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: AppliquerButton_3
        function AppliquerButton_3Pushed(app, event)

            %LUT


            global J;
            J=getimage(app.UIAxes2);
            global K;
            K=J;

            J=im2gray(getimage(app.UIAxes2));
            MIN=app.Slider_MIN.Value;
            MAX=app.Slider_MAX.Value;
            J =LUT(J,MIN,MAX);

            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: AppliquerButton_2
        function AppliquerButton_2Pushed(app, event)

            %             %CONVOLUTION
            function F = Convolution(a,kernel)
                red_channel = a(:, :, 1);
                green_channel = a(:, :, 2);
                blue_channel = a(:, :, 3);


                red_channel = imfilter(red_channel,kernel,'conv');
                green_channel = imfilter(green_channel,kernel,'conv');
                blue_channel = imfilter(blue_channel,kernel,'conv');

                F = cat(3, red_channel, green_channel, blue_channel);
            end


            global J;

            J=getimage(app.UIAxes2);
            global K;
            K=J;
            % K=[-2,-2,0,-2,7,0,0,0,0];
            %J=conv2(J,K,'same');

            a1=app.EditField.Value;a2=app.EditField_2.Value;a3=app.EditField_3.Value;
            a4=app.EditField_4.Value;a5=app.EditField_5.Value;a6=app.EditField_6.Value;
            a7=app.EditField_7.Value;a8=app.EditField_8.Value;a9=app.EditField_7.Value;
            Kernel=[a1,a1,a2,a3,a4,a5,a6,a7,a8,a9];
            J=Convolution(J,Kernel);

            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: BruitButton
        function BruitButtonPushed(app, event)

            global J;

            J=getimage(app.UIAxes2);
            global K;
            K=J;
            J =bruit(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: BruitImpulseButton
        function BruitImpulseButtonPushed(app, event)

            global J;

            J=getimage(app.UIAxes2);
            global K;
            K=J;
            J =bruitImpulse(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: SeuillageButton
        function SeuillageButtonPushed(app, event)

            global J;

            J=getimage(app.UIAxes2);
            global K;
            K=J;
            J=im2gray(J);
            N=app.Spinner_2.Value;
            J =seuillage(J,N);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)

            global J;
            J=getimage(app.UIAxes2);
            global K;
            K=J;
            J=im2gray(getimage(app.UIAxes2));
            J =Prewitt(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: RobertButton
        function RobertButtonPushed(app, event)

            global J;

            J=getimage(app.UIAxes2);
            global K;
            K=J;
            J =Robert(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)

            global J;
            J=getimage(app.UIAxes2);
            global K;
            K=J;
            J=im2gray(getimage(app.UIAxes2));
            J =Sobel(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: BaseTVButton
        function BaseTVButtonPushed(app, event)

            global J;

            J=getimage(app.UIAxes2);
            global K;
            K=J;

            J =base(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: AppliquerButton_5
        function AppliquerButton_5Pushed(app, event)

            global J;

            J=getimage(app.UIAxes2);
            global K;
            K=J;
            N=app.resize.Value;
            J = imresize(J,[N NaN]);%imresize(J,N);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: NegatifButton
        function NegatifButtonPushed(app, event)

            global J;

            J=getimage(app.UIAxes2);
            global K;
            K=J;
            J=im2gray(getimage(app.UIAxes2)); %a;

            J =negatif(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');

        end

        % Button pushed function: SaturationButton
        function SaturationButtonPushed(app, event)


            global J;
            J=getimage(app.UIAxes2);
            global K;
            K=J;
            J=im2gray(getimage(app.UIAxes2));
            J =satru(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Menu selected function: EnregistrerlimageMenu
        function EnregistrerlimageMenuSelected(app, event)
            im1=getimage(app.UIAxes2);
            [filename, pathname] = uiputfile('image_filtre.jpg', 'Enregistrer Image');
            filename=strcat(pathname,filename);
            if filename ~=0
                imwrite(im1,filename,'jpg');
                figure;
                imshow(filename);
            end
        end

        % Menu selected function: QuitterMenu
        function QuitterMenuSelected(app, event)

            selection = uiconfirm(app.UIFigure,'Vous voulez quitter?',...
                'Confirmation');

            switch selection
                case 'OK'
                    app.delete
                case 'Cancel'
                    return
            end
        end

        % Menu selected function: EnregistrerpuisquitterMenu
        function EnregistrerpuisquitterMenuSelected(app, event)
            %             axis(app.UIAxes2, 'tight');
            %             f=getframe(app.UIAxes2);
            %             im1 = f.cdata;
            im1=getimage(app.UIAxes2);
            [filename, pathname] = uiputfile('image_filtre.jpg', 'Enregitrer Image');
            filename=strcat(pathname,filename);
            if filename ~= 0

            imwrite(im1,filename,'jpg');
            
            app.delete;
            figure;
            imshow(filename);
            end
        end

        % Button pushed function: ErosionButton
        function ErosionButtonPushed(app, event)

            global J;
            st1=[ 1 1 1 1 1
                1 1 1 1 1
                1 1 1 1 1
                1 1 1 1 1
                1 1 1 1 1];
            J=getimage(app.UIAxes2);
            global K;
            K=J;
            J = imerode(J , st1) ;
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');

        end

        % Button pushed function: DilatationButton
        function DilatationButtonPushed(app, event)

            global J;
            st1=[ 1 1 1 1 1
                1 1 1 1 1
                1 1 1 1 1
                1 1 1 1 1
                1 1 1 1 1];
            J=getimage(app.UIAxes2);
            global K;
            K=J; %rgb2gray(a);
            J = imdilate(J , st1) ;

            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');

        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            app.delete
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            global a;
            global J;


            %             if app.Switch_2.Value == "Off"
            %                 axis(app.UIAxes2, 'tight');
            %                 f=getframe(app.UIAxes2);
            %                 im1 = f.cdata;
            %             end


            value = app.Switch.Value;

            switch value
                case 'Nouveau'
                    imshow(J,'Parent',app.UIAxes2)
                case 'Original'
                    imshow(a,'Parent',app.UIAxes2)
            end
        end

        % Button pushed function: AppliquerButton_4
        function AppliquerButton_4Pushed(app, event)

            global J;
            st1= [app.courdX.Value app.courdY.Value app.courdwidth.Value app.courdheight.Value];
            J=getimage(app.UIAxes2);
            global K;
            K=J; %rgb2gray(a);
            J = imcrop(J , st1) ;

            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');

        end

        % Button pushed function: FourierButton
        function FourierButtonPushed(app, event)
            global J;
            %             st1 = imread('llama.jpg');

            J=getimage(app.UIAxes2);
            global K;
            K=J;

            [fileName, pathName] = uigetfile('*.*', 'Choisir La 2eme Image');

            % Open the selected image
            fullPath = fullfile(pathName, fileName);
            
            if fullPath ~= 0
                img1 = imread(fullPath);
                img2 = getimage(app.UIAxes2);

                imageA = im2gray(img2);
                imageB = im2gray(img1);

                fftA = fft2(double(imageB));
                fftB = fft2(double(imageA));
                try

                fftC = abs(fftA).*exp(1i*angle(fftB));
                J = ifft2(fftC);
                catch ME
                % If problem reading image, display error message  
                uialert(app.UIFigure, ME.message, 'Image Erreur');
                end

                imshow(J,'Parent',app.UIAxes2);
                axis(app.UIAxes2, 'tight');
            end


        end

        % Menu selected function: AnnulerMenu
        function AnnulerMenuSelected(app, event)
            global K;
            imshow(K,'Parent',app.UIAxes2);
        end

        % Menu selected function: ReappliquerMenu
        function ReappliquerMenuSelected(app, event)
            global J;
            imshow(J,'Parent',app.UIAxes2);
        end

        % Menu selected function: RessayerMenu
        function RessayerMenuSelected(app, event)
            RessayerButtonPushed(app, event);
        end

        % Menu selected function: Rotation180Menu
        function Rotation180MenuSelected(app, event)
            %ROTATION


            global J;

            J=getimage(app.UIAxes2);
            global K;
            K=J;

            J =imrotate(J,180);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Menu selected function: MiroirMenu
        function MiroirMenuSelected(app, event)
            MiroirHorizontalButtonPushed(app, event);
        end

        % Menu selected function: BruitMenu_2
        function BruitMenu_2Selected(app, event)
            BruitButtonPushed(app, event);
        end

        % Menu selected function: ImpulseMenu
        function ImpulseMenuSelected(app, event)
            BruitImpulseButtonPushed(app, event);
        end

        % Menu selected function: GaussianMenu
        function GaussianMenuSelected(app, event)
            GaussianButtonPushed(app, event);
        end

        % Menu selected function: MedianMenu
        function MedianMenuSelected(app, event)
            MedianButtonPushed(app, event);
        end

        % Menu selected function: GrisMenu
        function GrisMenuSelected(app, event)
            GreyScaleButtonPushed(app, event);
        end

        % Menu selected function: EmailMenu
        function EmailMenuSelected(app, event)

            email = 'walidbosso15@gmail.com';
            url = ['mailto:',email];
            web(url)
        end

        % Menu selected function: FacebookMenu
        function FacebookMenuSelected(app, event)
            url = 'https://www.facebook.com/Walid.Bosso';
            web(url)
        end

        % Menu selected function: LinkedInMenu
        function LinkedInMenuSelected(app, event)
            url = 'https://www.linkedin.com/in/walidbosso/';
            web(url)
        end

        % Menu selected function: CVMenu
        function CVMenuSelected(app, event)
            url = 'https://walidbosso.w3spaces.com';
            web(url)
        end

        % Button pushed function: AdditionButton
        function AdditionButtonPushed(app, event)
            

            global J;
            %             st1 = imread('llama.jpg');

            J=getimage(app.UIAxes2);
            global K;
            K=J;

            [fileName, pathName] = uigetfile('*.*', 'Choisir La 2eme Image');

            % Open the selected image
            fullPath = fullfile(pathName, fileName);

            if fullPath ~= 0
                img1 = imread(fullPath);
                img2 = getimage(app.UIAxes2);
                try
                J=imadd(img1,img2);%immultiply( X , Y )
                catch ME
                % If problem reading image, display error message  
                uialert(app.UIFigure, ME.message, 'Image Erreur');
                end
                imshow(J,'Parent',app.UIAxes2);
                axis(app.UIAxes2, 'tight');
            end
        end

        % Menu selected function: MoyenMenu
        function MoyenMenuSelected(app, event)
            MoyenButtonPushed(app, event);
        end

        % Button pushed function: MultiplicationButton
        function MultiplicationButtonPushed(app, event)
             

            global J;
            %             st1 = imread('llama.jpg');

            J=getimage(app.UIAxes2);
            global K;
            K=J;

            [fileName, pathName] = uigetfile('*.*', 'Choisir La 2eme Image');

            % Open the selected image
            fullPath = fullfile(pathName, fileName);

            if fullPath ~= 0
                img1 = imread(fullPath);
                img2 = getimage(app.UIAxes2);
                try
                J=immultiply(img1,img2);%immultiply( X , 2 )
                catch ME
                % If problem reading image, display error message  
                uialert(app.UIFigure, ME.message, 'Image Error');
                end
                imshow(J,'Parent',app.UIAxes2);
                axis(app.UIAxes2, 'tight');
            end
        end

        % Button pushed function: SoustractionButton
        function SoustractionButtonPushed(app, event)
            
            global J;
            %             st1 = imread('llama.jpg');

            J=getimage(app.UIAxes2);
            global K;
            K=J;

            [fileName, pathName] = uigetfile('*.*', 'Choisir La 2eme Image');

            % Open the selected image
            fullPath = fullfile(pathName, fileName);

            if fullPath ~= 0
                img1 = imread(fullPath);
                img2 = getimage(app.UIAxes2);
                try
                J=imsubtract(img1,img2);%immultiply( X , Y )
                catch ME
                % If problem reading image, display error message  
                uialert(app.UIFigure, ME.message, 'Image Erreur');
                end
                imshow(J,'Parent',app.UIAxes2);
                axis(app.UIAxes2, 'tight');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FichierMenu = uimenu(app.UIFigure);
            app.FichierMenu.Text = 'Fichier';
            app.ChoisirlimageMenu = uimenu(app.FichierMenu);
            app.ChoisirlimageMenu.MenuSelectedFcn = createCallbackFcn(app, @ChoisirlimageMenuSelected, true);
            app.ChoisirlimageMenu.Accelerator = 'o';
            app.ChoisirlimageMenu.Text = 'Choisir l''image';
            app.EnregistrerlimageMenu = uimenu(app.FichierMenu);
            app.EnregistrerlimageMenu.MenuSelectedFcn = createCallbackFcn(app, @EnregistrerlimageMenuSelected, true);
            app.EnregistrerlimageMenu.Visible = 'off';
            app.EnregistrerlimageMenu.Accelerator = 's';
            app.EnregistrerlimageMenu.Text = 'Enregistrer l''image';
            app.EnregistrerpuisquitterMenu = uimenu(app.FichierMenu);
            app.EnregistrerpuisquitterMenu.MenuSelectedFcn = createCallbackFcn(app, @EnregistrerpuisquitterMenuSelected, true);
            app.EnregistrerpuisquitterMenu.Visible = 'off';
            app.EnregistrerpuisquitterMenu.Text = 'Enregistrer puis quitter';
            app.QuitterMenu = uimenu(app.FichierMenu);
            app.QuitterMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitterMenuSelected, true);
            app.QuitterMenu.Accelerator = 'w';
            app.QuitterMenu.Text = 'Quitter';
            app.EditionMenu = uimenu(app.UIFigure);
            app.EditionMenu.Text = 'Edition';
            app.AnnulerMenu = uimenu(app.EditionMenu);
            app.AnnulerMenu.MenuSelectedFcn = createCallbackFcn(app, @AnnulerMenuSelected, true);
            app.AnnulerMenu.Visible = 'off';
            app.AnnulerMenu.Accelerator = 'z';
            app.AnnulerMenu.Text = 'Annuler';
            app.ReappliquerMenu = uimenu(app.EditionMenu);
            app.ReappliquerMenu.MenuSelectedFcn = createCallbackFcn(app, @ReappliquerMenuSelected, true);
            app.ReappliquerMenu.Visible = 'off';
            app.ReappliquerMenu.Accelerator = 'y';
            app.ReappliquerMenu.Text = 'Re-appliquer';
            app.RessayerMenu = uimenu(app.EditionMenu);
            app.RessayerMenu.MenuSelectedFcn = createCallbackFcn(app, @RessayerMenuSelected, true);
            app.RessayerMenu.Visible = 'off';
            app.RessayerMenu.Accelerator = 'R';
            app.RessayerMenu.Text = 'Ressayer';
            app.TransofmrationMenu = uimenu(app.EditionMenu);
            app.TransofmrationMenu.Visible = 'off';
            app.TransofmrationMenu.Text = 'Transofmration';
            app.MiroirMenu = uimenu(app.TransofmrationMenu);
            app.MiroirMenu.MenuSelectedFcn = createCallbackFcn(app, @MiroirMenuSelected, true);
            app.MiroirMenu.Text = 'Miroir';
            app.Rotation180Menu = uimenu(app.TransofmrationMenu);
            app.Rotation180Menu.MenuSelectedFcn = createCallbackFcn(app, @Rotation180MenuSelected, true);
            app.Rotation180Menu.Text = 'Rotation 180°';
            app.FiltresMenu = uimenu(app.UIFigure);
            app.FiltresMenu.Text = 'Filtres';
            app.GrisMenu = uimenu(app.FiltresMenu);
            app.GrisMenu.MenuSelectedFcn = createCallbackFcn(app, @GrisMenuSelected, true);
            app.GrisMenu.Visible = 'off';
            app.GrisMenu.Text = 'Gris';
            app.BruitMenu = uimenu(app.FiltresMenu);
            app.BruitMenu.Visible = 'off';
            app.BruitMenu.Text = 'Bruit';
            app.BruitMenu_2 = uimenu(app.BruitMenu);
            app.BruitMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BruitMenu_2Selected, true);
            app.BruitMenu_2.Text = 'Bruit';
            app.ImpulseMenu = uimenu(app.BruitMenu);
            app.ImpulseMenu.MenuSelectedFcn = createCallbackFcn(app, @ImpulseMenuSelected, true);
            app.ImpulseMenu.Text = 'Impulse';
            app.GaussianMenu = uimenu(app.FiltresMenu);
            app.GaussianMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianMenuSelected, true);
            app.GaussianMenu.Visible = 'off';
            app.GaussianMenu.Text = 'Gaussian';
            app.MedianMenu = uimenu(app.FiltresMenu);
            app.MedianMenu.MenuSelectedFcn = createCallbackFcn(app, @MedianMenuSelected, true);
            app.MedianMenu.Visible = 'off';
            app.MedianMenu.Text = 'Median';
            app.MoyenMenu = uimenu(app.FiltresMenu);
            app.MoyenMenu.MenuSelectedFcn = createCallbackFcn(app, @MoyenMenuSelected, true);
            app.MoyenMenu.Visible = 'off';
            app.MoyenMenu.Text = 'Moyen';
            app.ContactMenu = uimenu(app.UIFigure);
            app.ContactMenu.Text = 'Contact';
            app.LinkedInMenu = uimenu(app.ContactMenu);
            app.LinkedInMenu.MenuSelectedFcn = createCallbackFcn(app, @LinkedInMenuSelected, true);
            app.LinkedInMenu.Text = 'LinkedIn';
            app.FacebookMenu = uimenu(app.ContactMenu);
            app.FacebookMenu.MenuSelectedFcn = createCallbackFcn(app, @FacebookMenuSelected, true);
            app.FacebookMenu.Text = 'Facebook';
            app.EmailMenu = uimenu(app.ContactMenu);
            app.EmailMenu.MenuSelectedFcn = createCallbackFcn(app, @EmailMenuSelected, true);
            app.EmailMenu.Text = 'E-mail';
            app.CVMenu = uimenu(app.ContactMenu);
            app.CVMenu.MenuSelectedFcn = createCallbackFcn(app, @CVMenuSelected, true);
            app.CVMenu.Text = 'CV';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.ChoisirlimageButton_2 = uibutton(app.UIFigure, 'push');
            app.ChoisirlimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChoisirlimageButton_2Pushed, true);
            app.ChoisirlimageButton_2.Text = 'Choisir l''image';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.autorise_2 = uilabel(app.UIFigure);
            app.autorise_2.Text = 'Plaisir de choisir une image';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Visible = 'off';
            app.RotationTab = uitab(app.TabGroup);
            app.RotationTab.Title = 'Rotation';
            app.RotationLabel = uilabel(app.RotationTab);
            app.RotationLabel.Text = 'Rotation';
            app.AppliquerButton = uibutton(app.RotationTab, 'push');
            app.AppliquerButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButtonPushed, true);
            app.AppliquerButton.Text = 'Appliquer';
            app.ValeursKnobLabel = uilabel(app.RotationTab);
            app.ValeursKnobLabel.Text = 'Valeurs';
            app.ValeursKnob = uiknob(app.RotationTab, 'continuous');
            app.ValeursKnob.Limits = [0 360];
            app.ValeursKnob.Value = 180;
            app.LuminanceTab = uitab(app.TabGroup);
            app.LuminanceTab.Title = 'Luminance';
            app.LuminancePlusButton = uibutton(app.LuminanceTab, 'push');
            app.LuminancePlusButton.ButtonPushedFcn = createCallbackFcn(app, @LuminancePlusButtonPushed, true);
            app.LuminancePlusButton.Text = {'Luminance '; 'Plus'};
            app.LuminanceMoinsButton = uibutton(app.LuminanceTab, 'push');
            app.LuminanceMoinsButton.ButtonPushedFcn = createCallbackFcn(app, @LuminanceMoinsButtonPushed, true);
            app.LuminanceMoinsButton.Text = {'Luminance '; 'Moins'};
            app.Slider = uislider(app.LuminanceTab);
            app.Slider.Limits = [0 200];
            app.Slider.Value = 40;
            app.LuminanceLabel = uilabel(app.LuminanceTab);
            app.LuminanceLabel.Text = 'Luminance';
            app.GrisTab = uitab(app.TabGroup);
            app.GrisTab.Title = 'Gris';
            app.FiltreGrisLabel = uilabel(app.GrisTab);
            app.FiltreGrisLabel.Text = 'Filtre Gris';
            app.GreyScaleButton = uibutton(app.GrisTab, 'push');
            app.GreyScaleButton.ButtonPushedFcn = createCallbackFcn(app, @GreyScaleButtonPushed, true);
            app.GreyScaleButton.Text = 'GreyScale';
            app.BruitTab = uitab(app.TabGroup);
            app.BruitTab.Title = 'Bruit';
            app.BruitLabel = uilabel(app.BruitTab);
            app.BruitLabel.Text = 'Bruit';
            app.BruitButton = uibutton(app.BruitTab, 'push');
            app.BruitButton.ButtonPushedFcn = createCallbackFcn(app, @BruitButtonPushed, true);
            app.BruitButton.Text = 'Bruit';
            app.BruitImpulseButton = uibutton(app.BruitTab, 'push');
            app.BruitImpulseButton.ButtonPushedFcn = createCallbackFcn(app, @BruitImpulseButtonPushed, true);
            app.BruitImpulseButton.Text = 'Bruit Impulse';
            app.FiltresflouTab = uitab(app.TabGroup);
            app.FiltresflouTab.Title = 'Filtres flou';
            app.FiltresButtonGroup = uibuttongroup(app.FiltresflouTab);
            app.FiltresButtonGroup.Title = 'Filtres';
            app.MedianButton = uibutton(app.FiltresButtonGroup, 'push');
            app.MedianButton.ButtonPushedFcn = createCallbackFcn(app, @MedianButtonPushed, true);
            app.MedianButton.Text = 'Median';
            app.GaussianButton = uibutton(app.FiltresButtonGroup, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.MoyenButton = uibutton(app.FiltresButtonGroup, 'push');
            app.MoyenButton.ButtonPushedFcn = createCallbackFcn(app, @MoyenButtonPushed, true);
            app.MoyenButton.Text = 'Moyen';
            app.Spinner_3 = uispinner(app.FiltresButtonGroup);
            app.Spinner_3.Value = 10;
            app.ContourTab = uitab(app.TabGroup);
            app.ContourTab.Title = 'Contour';
            app.PrewittButton = uibutton(app.ContourTab, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uibutton(app.ContourTab, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.RobertButton = uibutton(app.ContourTab, 'push');
            app.RobertButton.ButtonPushedFcn = createCallbackFcn(app, @RobertButtonPushed, true);
            app.RobertButton.Text = 'Robert';
            app.ContoursLabel = uilabel(app.ContourTab);
            app.ContoursLabel.Text = 'Contours';
            app.MiroirTab = uitab(app.TabGroup);
            app.MiroirTab.Title = 'Miroir';
            app.MiroirLabel = uilabel(app.MiroirTab);
            app.MiroirLabel.Text = 'Miroir';
            app.MiroirHorizontalButton = uibutton(app.MiroirTab, 'push');
            app.MiroirHorizontalButton.ButtonPushedFcn = createCallbackFcn(app, @MiroirHorizontalButtonPushed, true);
            app.MiroirHorizontalButton.Text = 'Miroir Horizontal';
            app.MiroirVerticalButton = uibutton(app.MiroirTab, 'push');
            app.MiroirVerticalButton.ButtonPushedFcn = createCallbackFcn(app, @MiroirVerticalButtonPushed, true);
            app.MiroirVerticalButton.Text = 'Miroir Vertical';
            app.HistogrammeTab = uitab(app.TabGroup);
            app.HistogrammeTab.Title = 'Histogramme';
            app.UIAxes = uiaxes(app.HistogrammeTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.HistogrammeButton = uibutton(app.HistogrammeTab, 'push');
            app.HistogrammeButton.ButtonPushedFcn = createCallbackFcn(app, @HistogrammeButtonPushed, true);
            app.HistogrammeButton.Text = 'Histogramme';
            app.PlotsLabel = uilabel(app.HistogrammeTab);
            app.PlotsLabel.Text = 'Plots';
            app.HistogrammeEqualizationButton = uibutton(app.HistogrammeTab, 'push');
            app.HistogrammeEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogrammeEqualizationButtonPushed, true);
            app.HistogrammeEqualizationButton.Text = {'Histogramme'; 'Equalization'};
            app.FiltreLabel = uilabel(app.HistogrammeTab);
            app.FiltreLabel.Text = 'Filtre';
            app.SeuillageTab = uitab(app.TabGroup);
            app.SeuillageTab.Title = 'Seuillage';
            app.SeuillageLabel = uilabel(app.SeuillageTab);
            app.SeuillageLabel.Text = 'Seuillage';
            app.SeuillageButton = uibutton(app.SeuillageTab, 'push');
            app.SeuillageButton.ButtonPushedFcn = createCallbackFcn(app, @SeuillageButtonPushed, true);
            app.SeuillageButton.Text = 'Seuillage';
            app.Spinner_2 = uispinner(app.SeuillageTab);
            app.Spinner_2.Value = 120;
            app.LUTTab = uitab(app.TabGroup);
            app.LUTTab.Title = 'LUT';
            app.Slider_MAX = uislider(app.LUTTab);
            app.Slider_MAX.Limits = [0 200];
            app.LUTLabel = uilabel(app.LUTTab);
            app.LUTLabel.Text = 'LUT';
            app.AppliquerButton_3 = uibutton(app.LUTTab, 'push');
            app.AppliquerButton_3.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButton_3Pushed, true);
            app.AppliquerButton_3.Text = 'Appliquer';
            app.Slider_MIN = uislider(app.LUTTab);
            app.Slider_MIN.Limits = [0 200];
            app.Slider_MIN.Value = 200;
            app.MAXLabel = uilabel(app.LUTTab);
            app.MAXLabel.Text = 'MAX';
            app.MINLabel = uilabel(app.LUTTab);
            app.MINLabel.Text = 'MIN';
            app.ConvolutionTab = uitab(app.TabGroup);
            app.ConvolutionTab.Title = 'Convolution';
            app.ConvolutionLabel = uilabel(app.ConvolutionTab);
            app.ConvolutionLabel.Text = 'Convolution';
            app.AppliquerButton_2 = uibutton(app.ConvolutionTab, 'push');
            app.AppliquerButton_2.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButton_2Pushed, true);
            app.AppliquerButton_2.Text = 'Appliquer';
            app.EditField = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.Value = -2;
            app.EditField_2 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_2.RoundFractionalValues = 'on';
            app.EditField_2.Value = -2;
            app.EditField_3 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_3.RoundFractionalValues = 'on';
            app.EditField_4 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_4.RoundFractionalValues = 'on';
            app.EditField_4.Value = -2;
            app.EditField_5 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_5.RoundFractionalValues = 'on';
            app.EditField_5.Value = 7;
            app.EditField_6 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_6.RoundFractionalValues = 'on';
            app.EditField_7 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_7.RoundFractionalValues = 'on';
            app.EditField_8 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_8.RoundFractionalValues = 'on';
            app.EditField_9 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_9.RoundFractionalValues = 'on';
            app.PSExempledeembossFilterK220270000Label = uilabel(app.ConvolutionTab);
            app.PSExempledeembossFilterK220270000Label.Text = {'PS: Exemple de embossFilter'; '       K=[-2,-2,0,-2,7,0,0,0,0]'};
            app.CropTab = uitab(app.TabGroup);
            app.CropTab.Title = 'Crop';
            app.CROPLabel = uilabel(app.CropTab);
            app.CROPLabel.Text = 'CROP';
            app.courdX = uieditfield(app.CropTab, 'numeric');
            app.courdX.RoundFractionalValues = 'on';
            app.courdX.Value = 100;
            app.courdY = uieditfield(app.CropTab, 'numeric');
            app.courdY.RoundFractionalValues = 'on';
            app.courdY.Value = 100;
            app.courdwidth = uieditfield(app.CropTab, 'numeric');
            app.courdwidth.RoundFractionalValues = 'on';
            app.courdwidth.Value = 2000;
            app.courdheight = uieditfield(app.CropTab, 'numeric');
            app.courdheight.RoundFractionalValues = 'on';
            app.courdheight.Value = 2000;
            app.XLabel = uilabel(app.CropTab);
            app.XLabel.Text = 'X:';
            app.AppliquerButton_4 = uibutton(app.CropTab, 'push');
            app.AppliquerButton_4.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButton_4Pushed, true);
            app.AppliquerButton_4.Text = 'Appliquer';
            app.YLabel = uilabel(app.CropTab);
            app.YLabel.Text = 'Y:';
            app.HLabel_2 = uilabel(app.CropTab);
            app.HLabel_2.Text = 'H:';
            app.WLabel = uilabel(app.CropTab);
            app.WLabel.Text = 'W:';
            app.RsolutionTab = uitab(app.TabGroup);
            app.RsolutionTab.Title = 'Résolution';
            app.EchantillonageLabel = uilabel(app.RsolutionTab);
            app.EchantillonageLabel.Text = 'Echantillonage';
            app.AppliquerButton_5 = uibutton(app.RsolutionTab, 'push');
            app.AppliquerButton_5.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButton_5Pushed, true);
            app.AppliquerButton_5.Text = 'Appliquer';
            app.resize = uispinner(app.RsolutionTab);
            app.resize.Value = 50;
            app.ImagesTab = uitab(app.TabGroup);
            app.ImagesTab.Title = '2 Images';
            app.Operations2imagesLabel = uilabel(app.ImagesTab);
            app.Operations2imagesLabel.Text = 'Operations 2 images';
            app.FourierButton = uibutton(app.ImagesTab, 'push');
            app.FourierButton.ButtonPushedFcn = createCallbackFcn(app, @FourierButtonPushed, true);
            app.FourierButton.Text = 'Fourier';
            app.AdditionButton = uibutton(app.ImagesTab, 'push');
            app.AdditionButton.ButtonPushedFcn = createCallbackFcn(app, @AdditionButtonPushed, true);
            app.AdditionButton.Text = 'Addition';
            app.MultiplicationButton = uibutton(app.ImagesTab, 'push');
            app.MultiplicationButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplicationButtonPushed, true);
            app.MultiplicationButton.Text = 'Multiplication';
            app.SoustractionButton = uibutton(app.ImagesTab, 'push');
            app.SoustractionButton.ButtonPushedFcn = createCallbackFcn(app, @SoustractionButtonPushed, true);
            app.SoustractionButton.Text = 'Soustraction';
            app.AutresTab = uitab(app.TabGroup);
            app.AutresTab.Title = 'Autres';
            app.OperationsLabel = uilabel(app.AutresTab);
            app.OperationsLabel.Text = 'Operations';
            app.BaseTVButton = uibutton(app.AutresTab, 'push');
            app.BaseTVButton.ButtonPushedFcn = createCallbackFcn(app, @BaseTVButtonPushed, true);
            app.BaseTVButton.Text = 'Base TV';
            app.NegatifButton = uibutton(app.AutresTab, 'push');
            app.NegatifButton.ButtonPushedFcn = createCallbackFcn(app, @NegatifButtonPushed, true);
            app.NegatifButton.Text = 'Negatif';
            app.SaturationButton = uibutton(app.AutresTab, 'push');
            app.SaturationButton.ButtonPushedFcn = createCallbackFcn(app, @SaturationButtonPushed, true);
            app.SaturationButton.Text = 'Saturation';
            app.DilatationButton = uibutton(app.AutresTab, 'push');
            app.DilatationButton.ButtonPushedFcn = createCallbackFcn(app, @DilatationButtonPushed, true);
            app.DilatationButton.Text = 'Dilatation';
            app.ErosionButton = uibutton(app.AutresTab, 'push');
            app.ErosionButton.ButtonPushedFcn = createCallbackFcn(app, @ErosionButtonPushed, true);
            app.ErosionButton.Text = 'Erosion';
            app.FiltresLabel = uilabel(app.AutresTab);
            app.FiltresLabel.Text = 'Filtres';
            app.Hyperlink = uihyperlink(app.AutresTab);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Enable = 'off';
            app.Hyperlink.Visible = 'off';
            app.Hyperlink.URL = '../filtre_test1.mlapp';
            app.Hyperlink.Text = 'Traitement de video';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Visible = 'off';
            app.Label_5.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'i.jpeg');
            app.PROJETTRAITEMENTDIMAGESLabel = uilabel(app.UIFigure);
            app.PROJETTRAITEMENTDIMAGESLabel.Text = 'PROJET TRAITEMENT D''IMAGES';
            app.ChoisirlimageButton = uibutton(app.UIFigure, 'push');
            app.ChoisirlimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChoisirlimageButtonPushed, true);
            app.ChoisirlimageButton.Visible = 'off';
            app.ChoisirlimageButton.Text = 'Choisir l''image';
            app.RalisparBOUSSOUWalidLabel = uilabel(app.UIFigure);
            app.RalisparBOUSSOUWalidLabel.Text = 'Réalisé par: BOUSSOU Walid';
            app.EnregistrerlimageButton = uibutton(app.UIFigure, 'push');
            app.EnregistrerlimageButton.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerlimageButtonPushed, true);
            app.EnregistrerlimageButton.Visible = 'off';
            app.EnregistrerlimageButton.Text = 'Enregistrer l''image';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'i.jpeg');
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = '<hr color="blue" />	<hr color="blue" width="80%" size="1px" />	<hr color="blue" width="40%" size="1px" />';
            app.HTML.Visible = 'off';
            app.HTML_2 = uihtml(app.UIFigure);
            app.HTML_2.HTMLSource = '<hr color="blue" />	<hr color="blue" width="80%" size="1px" />	<hr color="blue" width="40%" size="1px" />';
            app.HTML_2.Visible = 'off';
            app.HTML_5 = uihtml(app.UIFigure);
            app.HTML_5.HTMLSource = '<hr color="l" />	';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Visible = 'off';
            app.autorise = uilabel(app.UIFigure);
            app.autorise.Visible = 'off';
            app.autorise.Text = '';
            app.RessayerButton = uibutton(app.UIFigure, 'push');
            app.RessayerButton.ButtonPushedFcn = createCallbackFcn(app, @RessayerButtonPushed, true);
            app.RessayerButton.Visible = 'off';
            app.RessayerButton.Text = 'Ressayer';
            app.HTML_6 = uihtml(app.UIFigure);
            app.HTML_6.HTMLSource = '<hr color="blue" />	<hr color="blue" width="80%" size="1px" />	<hr color="blue" width="40%" size="1px" />';
            app.HTML_6.Visible = 'off';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Nouveau', 'Original'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Visible = 'off';
            app.Switch.Value = 'Nouveau';
            app.HTML_7 = uihtml(app.UIFigure);
            app.HTML_7.HTMLSource = '<hr color="blue" />	<hr color="blue" width="80%" size="1px" />	<hr color="blue" width="40%" size="1px" />';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_Filtres_BOUSSOU_Walid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end