classdef APPLICATION_FILTRES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FichierMenu                    matlab.ui.container.Menu
        ChoisirlimageMenu              matlab.ui.container.Menu
        EnregistrerlimageMenu          matlab.ui.container.Menu
        EnregistrerpuisquitterMenu     matlab.ui.container.Menu
        QuitterMenu                    matlab.ui.container.Menu
        PartagerMenu                   matlab.ui.container.Menu
        Switch                         matlab.ui.control.Switch
        HTML_6                         matlab.ui.control.HTML
        RessayerButton                 matlab.ui.control.Button
        autorise                       matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        HTML_5                         matlab.ui.control.HTML
        HTML_2                         matlab.ui.control.HTML
        HTML                           matlab.ui.control.HTML
        Image_2                        matlab.ui.control.Image
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        EnregistrerlimageButton        matlab.ui.control.Button
        RalisparBOUSSOUWalidLabel      matlab.ui.control.Label
        ChoisirlimageButton            matlab.ui.control.Button
        PROJETTRAITEMENTDIMAGESLabel   matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Label_5                        matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        LuminanceTab                   matlab.ui.container.Tab
        LuminanceLabel                 matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        LuminanceMoinsButton           matlab.ui.control.Button
        LuminancePlusButton            matlab.ui.control.Button
        RotationTab                    matlab.ui.container.Tab
        ValeursKnob                    matlab.ui.control.Knob
        ValeursKnobLabel               matlab.ui.control.Label
        AppliquerButton                matlab.ui.control.Button
        RotationLabel                  matlab.ui.control.Label
        BruitTab                       matlab.ui.container.Tab
        BruitImpulseButton             matlab.ui.control.Button
        BruitButton                    matlab.ui.control.Button
        BruitLabel                     matlab.ui.control.Label
        ContourTab                     matlab.ui.container.Tab
        ContoursLabel                  matlab.ui.control.Label
        RobertButton                   matlab.ui.control.Button
        SobelButton                    matlab.ui.control.Button
        PrewittButton                  matlab.ui.control.Button
        MiroirTab                      matlab.ui.container.Tab
        MiroirVerticalButton           matlab.ui.control.Button
        MiroirHorizontalButton         matlab.ui.control.Button
        MiroirLabel                    matlab.ui.control.Label
        GrisTab                        matlab.ui.container.Tab
        GreyScaleButton                matlab.ui.control.Button
        FiltreGrisLabel                matlab.ui.control.Label
        SeuillageTab                   matlab.ui.container.Tab
        Spinner_2                      matlab.ui.control.Spinner
        SeuillageButton                matlab.ui.control.Button
        SeuillageLabel                 matlab.ui.control.Label
        HistogrammeTab                 matlab.ui.container.Tab
        FiltreLabel                    matlab.ui.control.Label
        HistogrammeEqualizationButton  matlab.ui.control.Button
        PlotsLabel                     matlab.ui.control.Label
        HistogrammeButton              matlab.ui.control.Button
        LUTTab                         matlab.ui.container.Tab
        MINLabel                       matlab.ui.control.Label
        MAXLabel                       matlab.ui.control.Label
        Slider_MIN                     matlab.ui.control.Slider
        AppliquerButton_3              matlab.ui.control.Button
        LUTLabel                       matlab.ui.control.Label
        Slider_MAX                     matlab.ui.control.Slider
        ConvolutionTab                 matlab.ui.container.Tab
        PSExempledeembossFilterK220270000Label  matlab.ui.control.Label
        EditField_9                    matlab.ui.control.NumericEditField
        EditField_8                    matlab.ui.control.NumericEditField
        EditField_7                    matlab.ui.control.NumericEditField
        EditField_6                    matlab.ui.control.NumericEditField
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        AppliquerButton_2              matlab.ui.control.Button
        ConvolutionLabel               matlab.ui.control.Label
        FiltresTab                     matlab.ui.container.Tab
        FiltresButtonGroup             matlab.ui.container.ButtonGroup
        MoyenneButton                  matlab.ui.control.Button
        GaussianButton                 matlab.ui.control.Button
        MedianButton                   matlab.ui.control.Button
        AutresTab                      matlab.ui.container.Tab
        ResizeButton                   matlab.ui.control.Button
        Hyperlink                      matlab.ui.control.Hyperlink
        FiltresLabel                   matlab.ui.control.Label
        ErosionButton                  matlab.ui.control.Button
        DilatationButton               matlab.ui.control.Button
        GrisSaturButton                matlab.ui.control.Button
        NegatifButton                  matlab.ui.control.Button
        resize                         matlab.ui.control.Spinner
        ChangementEchelleButton        matlab.ui.control.Button
        BaseButton                     matlab.ui.control.Button
        OperationsLabel                matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Original
        Filter
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            global a;

            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            a=imread(filename);

            imshow(a,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
                        imshow(a,'Parent',app.UIAxes2);
                        axis(app.UIAxes2, 'tight');
            app.Lamp.Color='green';
            app.autorise.Text='Appliquer des filtres';

        end

        % Menu selected function: ChoisirlimageMenu
        function ChoisirlimageMenuSelected(app, event)
            global a;
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            a=imread(filename);

            imshow(a,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
                        imshow(a,'Parent',app.UIAxes2);
                        axis(app.UIAxes2, 'tight');
            app.Lamp.Color='green';
            app.autorise.Text='Appliquez des filtres';
            app.TabGroup.Visible = 'on';
            app.HTML_6.Visible = 'on';
            app.RessayerButton.Visible = 'on';
            app.HTML_2.Visible = 'on';
            app.EnregistrerlimageButton.Visible = 'on';
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.Switch.Visible = 'on';
            app.Label_5.Position = [430 2 198 556];
            app.ChoisirlimageButton.Text= "Choisir \n l' image";
            app.ChoisirlimageButton.Position= [486 362 88 43];
        end

        % Button pushed function: ChoisirlimageButton
        function ChoisirlimageButtonPushed(app, event)
            global a;
            global q;
            %[filename, pathname] = uigetfile('*.*', 'Pick an Image');
            %filename=strcat(pathname,filename);
            % a=imread(filename);
            app.Original=imread('tetouan.jpg');
%             import java.util.Stack
%             q = Stack();
%             q.push(a);
%             b=q.pop()

            imshow(app.Original,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
                        imshow(a,'Parent',app.UIAxes2);
                        axis(app.UIAxes2, 'tight');
            app.Lamp.Color='green';
            app.autorise.Text='Appliquez des filtres';
            app.TabGroup.Visible = 'on';
            app.HTML_6.Visible = 'on';
            app.RessayerButton.Visible = 'on';
            app.HTML_2.Visible = 'on';
            app.EnregistrerlimageButton.Visible = 'on';
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.Switch.Visible = 'on';
            app.Label_5.Position = [430 2 198 556];
        end

        % Button pushed function: EnregistrerlimageButton
        function EnregistrerlimageButtonPushed(app, event)
            im1=getimage(app.UIAxes2);
            % im=findobj(app.UIAxes2, 'type', 'image'); %ca marche mais type
            % Image et il veut pas convertir
            %im=findall(app.UIAxes2, 'type', 'image');

            %im=imhandles(app.UIAxes2); undefined
            %              im1 = double(im);
            %              I = ind2rgb(im1,Mcolor);
            % im1 = uint8(normalize(im, 'range', [0 255]));
            %imwrite(im, 'F:/output/filename.jpg', 'jpg'));


            %             axis(app.UIAxes2, 'tight');
            %             f=getframe(app.UIAxes2);
            %             im1 = f.cdata;
            imwrite(im1,'image_filtre.jpg','jpg');
            figure;
            imshow('image_filtre.jpg');

        end

        % Button pushed function: RessayerButton
        function RessayerButtonPushed(app, event)
            global a;
            app.Lamp.Color='red';
            app.autorise.Text=' ';
            imshow(a,'Parent',app.UIAxes2);
            app.Lamp.Color='green';
            app.autorise.Text='Ajouter des filtres';
        end

        % Button pushed function: GreyScaleButton
        function GreyScaleButtonPushed(app, event)
            global a;
            global J;
            %app.Filter=app.Original;
            J=getimage(app.UIAxes2);
            J =im2gray(J);
            %q.add(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: LuminanceMoinsButton
        function LuminanceMoinsButtonPushed(app, event)

            %             f=getframe(app.UIAxes2);
            %             im1 = f.cdata;
            
            global J;
            J=getimage(app.UIAxes2);
            R=app.Slider.Value;

            J =luminanceMoins(J,R);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: LuminancePlusButton
        function LuminancePlusButtonPushed(app, event)
            %             f=getframe(app.UIAxes2);
            %             im1 = f.cdata;
            global a;
            global J;
            J=getimage(app.UIAxes2);
            R=app.Slider.Value;

            J =luminancePlus(J,R);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: MedianButton
        function MedianButtonPushed(app, event)
            global a;
            global J;
            J=rgb2gray(getimage(app.UIAxes2));
            J =Median(J); %K =FiltreMedian(K ,3);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)
            global a;
            global J;
            J=rgb2gray(getimage(app.UIAxes2));
            J =Gaussian(J);
            %K =FiltreGaussien(K ,5, 7);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
            %             global a;
            %             img1=rgb2gray(a);
            %             img1=Gaussian_Tas(img1);
            %             imshow(img1,'Parent',app.UIAxes2);
            %             axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: MoyenneButton
        function MoyenneButtonPushed(app, event)
            global a;
            global J;
            J=rgb2gray(getimage(app.UIAxes2));
            J =Medium(J);%K =FiltreMoyenne(K ,5);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: MiroirVerticalButton
        function MiroirVerticalButtonPushed(app, event)
            global a;
            global J;

            J=getimage(app.UIAxes2);
            J =miroireV(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: MiroirHorizontalButton
        function MiroirHorizontalButtonPushed(app, event)
            global a;
            global J;

            J=getimage(app.UIAxes2);
            J =miroireH(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');

        end

        % Button pushed function: HistogrammeButton
        function HistogrammeButtonPushed(app, event)
            global a;
            global H;
            H=im2gray(getimage(app.UIAxes2));
            H =histograme(H);
            cla(app.UIAxes2, 'reset');

            app.UIAxes2.XColor='0.07,0.62,1.00';
            app.UIAxes2.YColor='0.00,0.45,0.74';
            app.UIAxes2.GridColor='0.00,0.45,0.74';
            app.UIAxes2.Title.String='Resultat';
            app.UIAxes2.FontName='Algerian';
            bar('Parent',app.UIAxes2,H);

        end

        % Button pushed function: HistogrammeEqualizationButton
        function HistogrammeEqualizationButtonPushed(app, event)
            global a;
            global J;

            J=im2gray(getimage(app.UIAxes2)); %a;
            J =histogramme_equalization(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: AppliquerButton
        function AppliquerButtonPushed(app, event)

            %ROTATION

            global a;
            global J;

            J=a;
            R=app.ValeursKnob.Value;
            J =imrotate(J,R);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: AppliquerButton_3
        function AppliquerButton_3Pushed(app, event)

            %LUT

            global a;
            global J;

            J=im2gray(getimage(app.UIAxes2));
            MIN=app.Slider_MIN.Value;
            MAX=app.Slider_MAX.Value;
            J =LUT(J,MIN,MAX);

            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: AppliquerButton_2
        function AppliquerButton_2Pushed(app, event)

            %CONVOLUTION

            global a;
            global J;

            J=getimage(app.UIAxes2);
            % K=[-2,-2,0,-2,7,0,0,0,0];
            %J=conv2(J,K,'same');

            a1=app.EditField.Value;a2=app.EditField_2.Value;a3=app.EditField_3.Value;
            a4=app.EditField_4.Value;a5=app.EditField_5.Value;a6=app.EditField_6.Value;
            a7=app.EditField_7.Value;a8=app.EditField_8.Value;a9=app.EditField_7.Value;
            K=[a1,a1,a2,a3,a4,a5,a6,a7,a8,a9];
            J=convn(J,K);

            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: BruitButton
        function BruitButtonPushed(app, event)
            global a;
            global J;

            J=getimage(app.UIAxes2);
            J =bruit(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: BruitImpulseButton
        function BruitImpulseButtonPushed(app, event)
            global a;
            global J;

            J=getimage(app.UIAxes2);
            J =bruitImpulse(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: SeuillageButton
        function SeuillageButtonPushed(app, event)

            global a;
            global J;
J=getimage(app.UIAxes2);
            J=im2gray(J);
            K=app.Spinner_2.Value;
            J =seuillage(J,K);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            global a;
            global J;

            J=im2gray(getimage(app.UIAxes2));
            J =Prewitt(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: RobertButton
        function RobertButtonPushed(app, event)
            global a;
            global J;

            J=getimage(app.UIAxes2);
            J =Robert(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            global a;
            global J;

            J=im2gray(getimage(app.UIAxes2));
            J =Sobel(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: BaseButton
        function BaseButtonPushed(app, event)
            global a;
            global J;

            J=getimage(app.UIAxes2);

            J =base(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: ChangementEchelleButton
        function ChangementEchelleButtonPushed(app, event)
            global a;
            global J;

            J=getimage(app.UIAxes2);
            K=app.resize.Value;
            J =resize_2(J,K);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Button pushed function: NegatifButton
        function NegatifButtonPushed(app, event)
            global a;
            global J;


            J=im2gray(getimage(app.UIAxes2)); %a;

            J =negatif(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');

        end

        % Button pushed function: GrisSaturButton
        function GrisSaturButtonPushed(app, event)

            global a;
            global J;
            J=im2gray(getimage(app.UIAxes2));
            J =satru(J);
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');
        end

        % Menu selected function: EnregistrerlimageMenu
        function EnregistrerlimageMenuSelected(app, event)
            %             axis(app.UIAxes2, 'tight');
            %             f=getframe(app.UIAxes2);
            im1 = getimage(app.UIAxes2);
            imwrite(im1,'image_filtre.jpg','jpg');
            figure;
            imshow('image_filtre.jpg');

        end

        % Menu selected function: QuitterMenu
        function QuitterMenuSelected(app, event)

            selection = uiconfirm(app.UIFigure,'Close the figure window?',...
                'Confirmation');

            switch selection
                case 'OK'
                    app.delete
                case 'Cancel'
                    return
            end
        end

        % Menu selected function: EnregistrerpuisquitterMenu
        function EnregistrerpuisquitterMenuSelected(app, event)
            %             axis(app.UIAxes2, 'tight');
            %             f=getframe(app.UIAxes2);
            %             im1 = f.cdata;
            getimage(app.UIAxes2);
            imwrite(im1,'image_filtre.jpg','jpg');
            % imshow('image_filtre.jpg');
            app.delete;
        end

        % Button pushed function: ErosionButton
        function ErosionButtonPushed(app, event)
            global a;
            global J;
            st1=[ 1 1 1 1 1
                1 1 1 1 1
                1 1 1 1 1
                1 1 1 1 1
                1 1 1 1 1];
            J= getimage(app.UIAxes2);
            J = imerode(J , st1) ;
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');

        end

        % Button pushed function: DilatationButton
        function DilatationButtonPushed(app, event)
            global a;
            global J;
            st1=[ 1 1 1 1 1
                1 1 1 1 1
                1 1 1 1 1
                1 1 1 1 1
                1 1 1 1 1];
            J= getimage(app.UIAxes2); %rgb2gray(a);
            J = imdilate(J , st1) ;
            
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');

        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            app.delete
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            global a;
            global J;
            

            %             if app.Switch_2.Value == "Off"
            %                 axis(app.UIAxes2, 'tight');
            %                 f=getframe(app.UIAxes2);
            %                 im1 = f.cdata;
            %             end


            value = app.Switch.Value;

            switch value
                case 'Nouveau'
                    imshow(J,'Parent',app.UIAxes2)
                case 'Original'
                    imshow(a,'Parent',app.UIAxes2)
            end
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
            global a;
            global J;
            st1=5;
            J= getimage(app.UIAxes2); %rgb2gray(a);
            J = imresize(J , st1) ;
            
            imshow(J,'Parent',app.UIAxes2);
            axis(app.UIAxes2, 'tight');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FichierMenu = uimenu(app.UIFigure);
            app.FichierMenu.Text = 'Fichier';
            app.ChoisirlimageMenu = uimenu(app.FichierMenu);
            app.ChoisirlimageMenu.MenuSelectedFcn = createCallbackFcn(app, @ChoisirlimageMenuSelected, true);
            app.ChoisirlimageMenu.Accelerator = 'o';
            app.ChoisirlimageMenu.Text = 'Choisir l''image';
            app.EnregistrerlimageMenu = uimenu(app.FichierMenu);
            app.EnregistrerlimageMenu.MenuSelectedFcn = createCallbackFcn(app, @EnregistrerlimageMenuSelected, true);
            app.EnregistrerlimageMenu.Accelerator = 's';
            app.EnregistrerlimageMenu.Text = 'Enregistrer l''image';
            app.EnregistrerpuisquitterMenu = uimenu(app.FichierMenu);
            app.EnregistrerpuisquitterMenu.MenuSelectedFcn = createCallbackFcn(app, @EnregistrerpuisquitterMenuSelected, true);
            app.EnregistrerpuisquitterMenu.Text = 'Enregistrer puis quitter';
            app.QuitterMenu = uimenu(app.FichierMenu);
            app.QuitterMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitterMenuSelected, true);
            app.QuitterMenu.Accelerator = 'w';
            app.QuitterMenu.Text = 'Quitter';
            app.PartagerMenu = uimenu(app.UIFigure);
            app.PartagerMenu.Text = 'Partager';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Resultat')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image originale')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Visible = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Visible = 'off';
            app.LuminanceTab = uitab(app.TabGroup);
            app.LuminanceTab.Title = 'Luminance';
            app.LuminancePlusButton = uibutton(app.LuminanceTab, 'push');
            app.LuminancePlusButton.ButtonPushedFcn = createCallbackFcn(app, @LuminancePlusButtonPushed, true);
            app.LuminancePlusButton.Text = {'Luminance '; 'Plus'};
            app.LuminanceMoinsButton = uibutton(app.LuminanceTab, 'push');
            app.LuminanceMoinsButton.ButtonPushedFcn = createCallbackFcn(app, @LuminanceMoinsButtonPushed, true);
            app.LuminanceMoinsButton.Text = {'Luminance '; 'Moins'};
            app.Slider = uislider(app.LuminanceTab);
            app.Slider.Limits = [0 200];
            app.Slider.Value = 40;
            app.LuminanceLabel = uilabel(app.LuminanceTab);
            app.LuminanceLabel.Text = 'Luminance';
            app.RotationTab = uitab(app.TabGroup);
            app.RotationTab.Title = 'Rotation';
            app.RotationLabel = uilabel(app.RotationTab);
            app.RotationLabel.Text = 'Rotation';
            app.AppliquerButton = uibutton(app.RotationTab, 'push');
            app.AppliquerButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButtonPushed, true);
            app.AppliquerButton.Text = 'Appliquer';
            app.ValeursKnobLabel = uilabel(app.RotationTab);
            app.ValeursKnobLabel.Text = 'Valeurs';
            app.ValeursKnob = uiknob(app.RotationTab, 'continuous');
            app.ValeursKnob.Limits = [0 360];
            app.ValeursKnob.Value = 3.07;
            app.BruitTab = uitab(app.TabGroup);
            app.BruitTab.Title = 'Bruit';
            app.BruitLabel = uilabel(app.BruitTab);
            app.BruitLabel.Text = 'Bruit';
            app.BruitButton = uibutton(app.BruitTab, 'push');
            app.BruitButton.ButtonPushedFcn = createCallbackFcn(app, @BruitButtonPushed, true);
            app.BruitButton.Text = 'Bruit';
            app.BruitImpulseButton = uibutton(app.BruitTab, 'push');
            app.BruitImpulseButton.ButtonPushedFcn = createCallbackFcn(app, @BruitImpulseButtonPushed, true);
            app.BruitImpulseButton.Text = 'Bruit Impulse';
            app.ContourTab = uitab(app.TabGroup);
            app.ContourTab.Title = 'Contour';
            app.PrewittButton = uibutton(app.ContourTab, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uibutton(app.ContourTab, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.RobertButton = uibutton(app.ContourTab, 'push');
            app.RobertButton.ButtonPushedFcn = createCallbackFcn(app, @RobertButtonPushed, true);
            app.RobertButton.Text = 'Robert';
            app.ContoursLabel = uilabel(app.ContourTab);
            app.ContoursLabel.Text = 'Contours';
            app.MiroirTab = uitab(app.TabGroup);
            app.MiroirTab.Title = 'Miroir';
            app.MiroirLabel = uilabel(app.MiroirTab);
            app.MiroirLabel.Text = 'Miroir';
            app.MiroirHorizontalButton = uibutton(app.MiroirTab, 'push');
            app.MiroirHorizontalButton.ButtonPushedFcn = createCallbackFcn(app, @MiroirHorizontalButtonPushed, true);
            app.MiroirHorizontalButton.Text = 'Miroir Horizontal';
            app.MiroirVerticalButton = uibutton(app.MiroirTab, 'push');
            app.MiroirVerticalButton.ButtonPushedFcn = createCallbackFcn(app, @MiroirVerticalButtonPushed, true);
            app.MiroirVerticalButton.Text = 'Miroir Vertical';
            app.GrisTab = uitab(app.TabGroup);
            app.GrisTab.Title = 'Gris';
            app.FiltreGrisLabel = uilabel(app.GrisTab);
            app.FiltreGrisLabel.Text = 'Filtre Gris';
            app.GreyScaleButton = uibutton(app.GrisTab, 'push');
            app.GreyScaleButton.ButtonPushedFcn = createCallbackFcn(app, @GreyScaleButtonPushed, true);
            app.GreyScaleButton.Text = 'GreyScale';
            app.SeuillageTab = uitab(app.TabGroup);
            app.SeuillageTab.Title = 'Seuillage';
            app.SeuillageLabel = uilabel(app.SeuillageTab);
            app.SeuillageLabel.Text = 'Seuillage';
            app.SeuillageButton = uibutton(app.SeuillageTab, 'push');
            app.SeuillageButton.ButtonPushedFcn = createCallbackFcn(app, @SeuillageButtonPushed, true);
            app.SeuillageButton.Text = 'Seuillage';
            app.Spinner_2 = uispinner(app.SeuillageTab);
            app.Spinner_2.Value = 120;
            app.HistogrammeTab = uitab(app.TabGroup);
            app.HistogrammeTab.Title = 'Histogramme';
            app.HistogrammeButton = uibutton(app.HistogrammeTab, 'push');
            app.HistogrammeButton.ButtonPushedFcn = createCallbackFcn(app, @HistogrammeButtonPushed, true);
            app.HistogrammeButton.Text = 'Histogramme';
            app.PlotsLabel = uilabel(app.HistogrammeTab);
            app.PlotsLabel.Text = 'Plots';
            app.HistogrammeEqualizationButton = uibutton(app.HistogrammeTab, 'push');
            app.HistogrammeEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogrammeEqualizationButtonPushed, true);
            app.HistogrammeEqualizationButton.Text = {'Histogramme'; 'Equalization'};
            app.FiltreLabel = uilabel(app.HistogrammeTab);
            app.FiltreLabel.Text = 'Filtre';
            app.LUTTab = uitab(app.TabGroup);
            app.LUTTab.Title = 'LUT';
            app.Slider_MAX = uislider(app.LUTTab);
            app.Slider_MAX.Limits = [0 200];
            app.LUTLabel = uilabel(app.LUTTab);
            app.LUTLabel.Text = 'LUT';
            app.AppliquerButton_3 = uibutton(app.LUTTab, 'push');
            app.AppliquerButton_3.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButton_3Pushed, true);
            app.AppliquerButton_3.Text = 'Appliquer';
            app.Slider_MIN = uislider(app.LUTTab);
            app.Slider_MIN.Limits = [0 200];
            app.Slider_MIN.Value = 200;
            app.MAXLabel = uilabel(app.LUTTab);
            app.MAXLabel.Text = 'MAX';
            app.MINLabel = uilabel(app.LUTTab);
            app.MINLabel.Text = 'MIN';
            app.ConvolutionTab = uitab(app.TabGroup);
            app.ConvolutionTab.Title = 'Convolution';
            app.ConvolutionLabel = uilabel(app.ConvolutionTab);
            app.ConvolutionLabel.Text = 'Convolution';
            app.AppliquerButton_2 = uibutton(app.ConvolutionTab, 'push');
            app.AppliquerButton_2.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButton_2Pushed, true);
            app.AppliquerButton_2.Text = 'Appliquer';
            app.EditField = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.Value = -2;
            app.EditField_2 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_2.RoundFractionalValues = 'on';
            app.EditField_2.Value = -2;
            app.EditField_3 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_3.RoundFractionalValues = 'on';
            app.EditField_4 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_4.RoundFractionalValues = 'on';
            app.EditField_4.Value = -2;
            app.EditField_5 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_5.RoundFractionalValues = 'on';
            app.EditField_5.Value = 7;
            app.EditField_6 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_6.RoundFractionalValues = 'on';
            app.EditField_7 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_7.RoundFractionalValues = 'on';
            app.EditField_8 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_8.RoundFractionalValues = 'on';
            app.EditField_9 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_9.RoundFractionalValues = 'on';
            app.PSExempledeembossFilterK220270000Label = uilabel(app.ConvolutionTab);
            app.PSExempledeembossFilterK220270000Label.Text = {'PS: Exemple de embossFilter'; '       K=[-2,-2,0,-2,7,0,0,0,0]'};
            app.FiltresTab = uitab(app.TabGroup);
            app.FiltresTab.Title = 'Filtres';
            app.FiltresButtonGroup = uibuttongroup(app.FiltresTab);
            app.FiltresButtonGroup.Title = 'Filtres';
            app.MedianButton = uibutton(app.FiltresButtonGroup, 'push');
            app.MedianButton.ButtonPushedFcn = createCallbackFcn(app, @MedianButtonPushed, true);
            app.MedianButton.Text = 'Median';
            app.GaussianButton = uibutton(app.FiltresButtonGroup, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.MoyenneButton = uibutton(app.FiltresButtonGroup, 'push');
            app.MoyenneButton.ButtonPushedFcn = createCallbackFcn(app, @MoyenneButtonPushed, true);
            app.MoyenneButton.Text = 'Moyenne';
            app.AutresTab = uitab(app.TabGroup);
            app.AutresTab.Title = 'Autres';
            app.OperationsLabel = uilabel(app.AutresTab);
            app.OperationsLabel.Text = 'Operations';
            app.BaseButton = uibutton(app.AutresTab, 'push');
            app.BaseButton.ButtonPushedFcn = createCallbackFcn(app, @BaseButtonPushed, true);
            app.BaseButton.Text = 'Base';
            app.ChangementEchelleButton = uibutton(app.AutresTab, 'push');
            app.ChangementEchelleButton.ButtonPushedFcn = createCallbackFcn(app, @ChangementEchelleButtonPushed, true);
            app.ChangementEchelleButton.Text = {'Changement'; 'Echelle'};
            app.resize = uispinner(app.AutresTab);
            app.resize.Value = 0.5;
            app.NegatifButton = uibutton(app.AutresTab, 'push');
            app.NegatifButton.ButtonPushedFcn = createCallbackFcn(app, @NegatifButtonPushed, true);
            app.NegatifButton.Text = 'Negatif';
            app.GrisSaturButton = uibutton(app.AutresTab, 'push');
            app.GrisSaturButton.ButtonPushedFcn = createCallbackFcn(app, @GrisSaturButtonPushed, true);
            app.GrisSaturButton.Text = {'Gris'; 'Saturé'};
            app.DilatationButton = uibutton(app.AutresTab, 'push');
            app.DilatationButton.ButtonPushedFcn = createCallbackFcn(app, @DilatationButtonPushed, true);
            app.DilatationButton.Text = 'Dilatation';
            app.ErosionButton = uibutton(app.AutresTab, 'push');
            app.ErosionButton.ButtonPushedFcn = createCallbackFcn(app, @ErosionButtonPushed, true);
            app.ErosionButton.Text = 'Erosion';
            app.FiltresLabel = uilabel(app.AutresTab);
            app.FiltresLabel.Text = 'Filtres';
            app.Hyperlink = uihyperlink(app.AutresTab);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.URL = '../filtre_test1.mlapp';
            app.Hyperlink.Text = 'Traitement de video';
            app.ResizeButton = uibutton(app.AutresTab, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'i.jpeg');
            app.PROJETTRAITEMENTDIMAGESLabel = uilabel(app.UIFigure);
            app.PROJETTRAITEMENTDIMAGESLabel.Text = 'PROJET TRAITEMENT D''IMAGES';
            app.ChoisirlimageButton = uibutton(app.UIFigure, 'push');
            app.ChoisirlimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChoisirlimageButtonPushed, true);
            app.ChoisirlimageButton.Text = 'Choisir l''image';
            app.RalisparBOUSSOUWalidLabel = uilabel(app.UIFigure);
            app.RalisparBOUSSOUWalidLabel.Text = 'Réalisé par: BOUSSOU Walid';
            app.EnregistrerlimageButton = uibutton(app.UIFigure, 'push');
            app.EnregistrerlimageButton.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerlimageButtonPushed, true);
            app.EnregistrerlimageButton.Visible = 'off';
            app.EnregistrerlimageButton.Text = 'Enregistrer l''image';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'i.jpeg');
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = '<hr color="blue" />	<hr color="blue" width="80%" size="1px" />	<hr color="blue" width="40%" size="1px" />';
            app.HTML_2 = uihtml(app.UIFigure);
            app.HTML_2.HTMLSource = '<hr color="blue" />	<hr color="blue" width="80%" size="1px" />	<hr color="blue" width="40%" size="1px" />';
            app.HTML_2.Visible = 'off';
            app.HTML_5 = uihtml(app.UIFigure);
            app.HTML_5.HTMLSource = '<hr color="l" />	';
            app.Lamp = uilamp(app.UIFigure);
            app.autorise = uilabel(app.UIFigure);
            app.autorise.Text = '';
            app.RessayerButton = uibutton(app.UIFigure, 'push');
            app.RessayerButton.ButtonPushedFcn = createCallbackFcn(app, @RessayerButtonPushed, true);
            app.RessayerButton.Visible = 'off';
            app.RessayerButton.Text = 'Ressayer';
            app.HTML_6 = uihtml(app.UIFigure);
            app.HTML_6.HTMLSource = '<hr color="blue" />	<hr color="blue" width="80%" size="1px" />	<hr color="blue" width="40%" size="1px" />';
            app.HTML_6.Visible = 'off';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Nouveau', 'Original'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Visible = 'off';
            app.Switch.Value = 'Nouveau';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APPLICATION_FILTRES

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end